classdef physician < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        name1Menu                       matlab.ui.container.Menu
        name2Menu                       matlab.ui.container.Menu
        name3Menu                       matlab.ui.container.Menu
        name4Menu                       matlab.ui.container.Menu
        limit3Label                     matlab.ui.control.Label
        someotherinformationLabel       matlab.ui.control.Label
        usedmldayTextAreaLabel          matlab.ui.control.Label
        usedmldayTextArea               matlab.ui.control.TextArea
        limit1Label                     matlab.ui.control.Label
        usedmlhrTextAreaLabel           matlab.ui.control.Label
        usedmlhrTextArea                matlab.ui.control.TextArea
        mlminLabel                      matlab.ui.control.Label
        mlshotLabel                     matlab.ui.control.Label
        mlLabel                         matlab.ui.control.Label
        baselinesetting00101EditFieldLabel  matlab.ui.control.Label
        baselinesetting00101EditField   matlab.ui.control.NumericEditField
        addtoEditFieldLabel             matlab.ui.control.Label
        addtoEditField                  matlab.ui.control.NumericEditField
        changeButton                    matlab.ui.control.Button
        changeButton_2                  matlab.ui.control.Button
        baselinemlminLabel              matlab.ui.control.Label
        baselinemlminTextArea           matlab.ui.control.TextArea
        bolusmlshotTextAreaLabel        matlab.ui.control.Label
        bolusmlshotTextArea             matlab.ui.control.TextArea
        addButton                       matlab.ui.control.Button
        medicineleftTextAreaLabel       matlab.ui.control.Label
        medicineleftTextArea            matlab.ui.control.TextArea
        mlLabel_2                       matlab.ui.control.Label
        bolussetting0205EditFieldLabel  matlab.ui.control.Label
        bolussetting0205EditField       matlab.ui.control.NumericEditField
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        chosenPatient
        
        patient1datalist
        patient1name
        patient1used
        patient2datalist
        patient2name
        patient2used
        patient3datalist
        patient3name
        patient3used
        patient4datalist
        patient4name
        patient4used
        
        patientdatalist
        patientname
        patientused
        namelist
    end
    
    properties (Access = private)
        
    end
    
    
    methods (Access = public)
        function reflect(app,patient_datalist)
            app.baselinemlminTextArea.Value = num2str(patient_datalist(1));
            app.bolusmlshotTextArea.Value = num2str(patient_datalist(2));
            app.usedmldayTextArea.Value = num2str(patient_datalist(3));
            app.usedmlhrTextArea.Value = num2str(patient_datalist(4));
            app.medicineleftTextArea.Value = num2str(patient_datalist(5));
        end
        function patient = sentence(app,chosenPatient)
            name = chosenPatient.name;
            switch name
                case app.namelist(1)
                    patient = app.patient1;
                case app.namelist(2)
                    patient = app.patient2;
                case app.namelist(3)
                    patient = app.patient3;
                case app.namelist(4)
                    patient = app.patient4;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function baseline00101TextAreaValueChanged(app, event)
            
        end

        % Callback function
        function bolussetting0205EditFieldValueChanged(app, event)
            
        end

        % Button pushed function: changeButton
        function changeButtonPushed(app, event)
            patient = app.sentence(app.chosenPatient);
            app.baselinemlminTextArea.Value = num2str(patient.datalist(1));
            value = app.baselinesetting00101EditField.Value;
            if value > 0.1
                app.TextArea_2.Value = "You cannot set more than 0.1.";
            elseif value < 0.01
                app.TextArea_2.Value = "You cannot set less than 0.01";
            else
                patient.datalist(1) = value;
                app.baselinemlminTextArea.Value = num2str(value);
            end
        end

        % Callback function
        function baselinesetting00101EditFieldValueChanged(app, event)
            
        end

        % Button pushed function: changeButton_2
        function changeButton_2Pushed(app, event)
            patient = app.sentence(app.chosenPatient);
            app.bolusmlshotTextArea.Value = num2str(patient.datalist(2));
            value = app.bolussetting0205EditField.Value;
            if value > 0.1
                app.TextArea_2.Value = "You cannot set more than 0.1.";
            elseif value < 0.01
                app.TextArea_2.Value = "You cannot set less than 0.01";
            else
                patient.datalist(2) = value;
                app.bolusmlshotTextArea.Value = num2str(value);
            end
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            patient = app.sentence(app.chosenPatient);
            app.medicineleftTextArea.Value = num2str(patient.datalist(2));
            value = app.addtoEditField.Value;
            if value < patient.datalist(5)
                app.TextArea_2.Value = "You have got enough medicine.";
            else
                patient.datalist(5) = value;
                app.medicineleftTextArea.Value = num2str(value);
            end
        end

        % Callback function
        function nameDropDownValueChanged(app, event)
            
        end

        % Menu selected function: name1Menu
        function name1MenuSelected(app, event)
         app.chosenPatient = app.namelist(1);
         app.patientdatalist = app.patient1datalist;
         app.reflect(app.patient1datalist);
         app.TextArea.Value = app.patient1name;
        end

        % Menu selected function: name2Menu
        function name2MenuSelected(app, event)
         app.chosenPatient = app.patient2;
         app.reflect(app.chosenPatient);
         app.TextArea.Value = app.patient1.name;
        end

        % Menu selected function: name3Menu
        function name3MenuSelected(app, event)
         app.chosenPatient = app.patient3;
         app.reflect(app.chosenPatient);
         app.TextArea.Value = app.patient1.name;
        end

        % Menu selected function: name4Menu
        function name4MenuSelected(app, event)
         app.chosenPatient = app.patient4;
         app.reflect(app.chosenPatient);
         app.TextArea.Value = app.patient1.name;
        end

        % Callback function
        function addButton_2Pushed(app, event)
            %app.addCustomMenuFcn(UIFigure,)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.name1Menu = uimenu(app.UIFigure);
            app.name1Menu.MenuSelectedFcn = createCallbackFcn(app, @name1MenuSelected, true);
            app.name1Menu.Text = 'name1';
            app.name2Menu = uimenu(app.UIFigure);
            app.name2Menu.MenuSelectedFcn = createCallbackFcn(app, @name2MenuSelected, true);
            app.name2Menu.Text = 'name2';
            app.name3Menu = uimenu(app.UIFigure);
            app.name3Menu.MenuSelectedFcn = createCallbackFcn(app, @name3MenuSelected, true);
            app.name3Menu.Text = 'name3';
            app.name4Menu = uimenu(app.UIFigure);
            app.name4Menu.MenuSelectedFcn = createCallbackFcn(app, @name4MenuSelected, true);
            app.name4Menu.Text = 'name4';
            app.limit3Label = uilabel(app.UIFigure);
            app.limit3Label.Text = '(limit: 3)';
            app.someotherinformationLabel = uilabel(app.UIFigure);
            app.someotherinformationLabel.Text = {'some other information'; '......'};
            app.usedmldayTextAreaLabel = uilabel(app.UIFigure);
            app.usedmldayTextAreaLabel.Text = 'used (ml/day)';
            app.usedmldayTextArea = uitextarea(app.UIFigure);
            app.usedmldayTextArea.Editable = 'off';
            app.limit1Label = uilabel(app.UIFigure);
            app.limit1Label.Text = '(limit: 1)';
            app.usedmlhrTextAreaLabel = uilabel(app.UIFigure);
            app.usedmlhrTextAreaLabel.Text = 'used (ml/hr)';
            app.usedmlhrTextArea = uitextarea(app.UIFigure);
            app.usedmlhrTextArea.Editable = 'off';
            app.mlminLabel = uilabel(app.UIFigure);
            app.mlminLabel.Text = '(ml/min)';
            app.mlshotLabel = uilabel(app.UIFigure);
            app.mlshotLabel.Text = '(ml/shot)';
            app.mlLabel = uilabel(app.UIFigure);
            app.mlLabel.Text = '(ml)';
            app.baselinesetting00101EditFieldLabel = uilabel(app.UIFigure);
            app.baselinesetting00101EditFieldLabel.Text = 'baseline setting(0.01-0.1)';
            app.baselinesetting00101EditField = uieditfield(app.UIFigure, 'numeric');
            app.baselinesetting00101EditField.Value = 0.01;
            app.addtoEditFieldLabel = uilabel(app.UIFigure);
            app.addtoEditFieldLabel.Text = 'add to';
            app.addtoEditField = uieditfield(app.UIFigure, 'numeric');
            app.addtoEditField.Value = 20;
            app.changeButton = uibutton(app.UIFigure, 'push');
            app.changeButton.ButtonPushedFcn = createCallbackFcn(app, @changeButtonPushed, true);
            app.changeButton.Text = 'change';
            app.changeButton_2 = uibutton(app.UIFigure, 'push');
            app.changeButton_2.ButtonPushedFcn = createCallbackFcn(app, @changeButton_2Pushed, true);
            app.changeButton_2.Text = 'change';
            app.baselinemlminLabel = uilabel(app.UIFigure);
            app.baselinemlminLabel.Text = 'baseline(ml/min)';
            app.baselinemlminTextArea = uitextarea(app.UIFigure);
            app.baselinemlminTextArea.Editable = 'off';
            app.bolusmlshotTextAreaLabel = uilabel(app.UIFigure);
            app.bolusmlshotTextAreaLabel.Text = 'bolus(ml/shot)';
            app.bolusmlshotTextArea = uitextarea(app.UIFigure);
            app.bolusmlshotTextArea.Editable = 'off';
            app.addButton = uibutton(app.UIFigure, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = 'add';
            app.medicineleftTextAreaLabel = uilabel(app.UIFigure);
            app.medicineleftTextAreaLabel.Text = 'medicine left:';
            app.medicineleftTextArea = uitextarea(app.UIFigure);
            app.medicineleftTextArea.Editable = 'off';
            app.mlLabel_2 = uilabel(app.UIFigure);
            app.mlLabel_2.Text = 'ml';
            app.bolussetting0205EditFieldLabel = uilabel(app.UIFigure);
            app.bolussetting0205EditFieldLabel.Text = 'bolus setting(0.2-0.5)';
            app.bolussetting0205EditField = uieditfield(app.UIFigure, 'numeric');
            app.bolussetting0205EditField.Value = 0.2;
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea_2 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = physician

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end